classdef GUI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        STOPButton                      matlab.ui.control.Button
        COMportEditFieldLabel           matlab.ui.control.Label
        COMportEditField                matlab.ui.control.NumericEditField
        ConnectButton                   matlab.ui.control.Button
        BitfrequencyEditFieldLabel      matlab.ui.control.Label
        BitfrequencyEditField           matlab.ui.control.NumericEditField
        Codeword8hexEditFieldLabel      matlab.ui.control.Label
        Codeword8hexEditField           matlab.ui.control.EditField
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        RepetitioncounterEditFieldLabel  matlab.ui.control.Label
        RepetitioncounterEditField      matlab.ui.control.NumericEditField
        ConfigAudioButton               matlab.ui.control.Button
        PlayaudioButton                 matlab.ui.control.Button
        StopaudioButton                 matlab.ui.control.Button
        WASDdrivingButton               matlab.ui.control.Button
        SteerGaugeLabel                 matlab.ui.control.Label
        SteerGauge                      matlab.ui.control.SemicircularGauge
        SpeedGaugeLabel                 matlab.ui.control.Label
        SpeedGauge                      matlab.ui.control.LinearGauge
        AudioLampLabel                  matlab.ui.control.Label
        AudioLamp                       matlab.ui.control.Lamp
        ConnectionLampLabel             matlab.ui.control.Label
        ConnectionLamp                  matlab.ui.control.Lamp
    end


    properties (Access = public)
    end

    properties (Access = private)
        com % Com port to be used
        Fb % Bit frequency to be used
        Cw % Code word to be used
        Fc % Carrier frequency to be used
        Rc % Repetition counter to be used
        connection % Veriable to indicate if the car is connected
    end


    methods (Access = private)

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stop();
            closeCom();
        end

        % Value changed function: COMportEditField
        function COMportEditFieldValueChanged(app, event)
            value = app.COMportEditField.Value;
            app.com = value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            str = sprintf('\\.\COM%d',app.com);
            tmp = EPOCommunications('open',str);
            if tmp
                app.connection = 1;
            end
        end

        % Value changed function: BitfrequencyEditField
        function BitfrequencyEditFieldValueChanged(app, event)
            value = app.BitfrequencyEditField.Value;
            app.Fb = value;
        end

        % Value changed function: Codeword8hexEditField
        function Codeword8hexEditFieldValueChanged(app, event)
            value = app.Codeword8hexEditField.Value;
            if length(value) > 8 || hex2dec(value) > 4294967295
                app.Cw = 00000000;
            else
                app.Cw = dec2hex(hex2dec(value));
            end
        end

        % Value changed function: CarrierFrequencyEditField
        function CarrierFrequencyEditFieldValueChanged(app, event)
            value = app.CarrierFrequencyEditField.Value;
            app.Fc = value;
        end

        % Value changed function: RepetitioncounterEditField
        function RepetitioncounterEditFieldValueChanged(app, event)
            value = app.RepetitioncounterEditField.Value;
            app.Rc = value;
        end

        % Button pushed function: ConfigAudioButton
        function ConfigAudioButtonPushed(app, event)
            m1 = sprintf('B%d',app.Fb);
            m2 = sprintf('C%d',app.Cw);
            m3 = sprintf('F%d',app.Fc);
            m4 = sprintf('R%d',app.Rc);
            EPOCommunications('transmit',m1);
            EPOCommunications('transmit',m2);
            EPOCommunications('transmit',m3);
            EPOCommunications('transmit',m4);
        end

        % Button pushed function: PlayaudioButton
        function PlayaudioButtonPushed(app, event)
            EPOCommunications('transmit','A1');
        end

        % Button pushed function: StopaudioButton
        function StopaudioButtonPushed(app, event)
            EPOCommunications('transmit','A0');
        end

        % Button pushed function: WASDdrivingButton
        function WASDdrivingButtonPushed(app, event)
            control_script_1
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP!';
            app.COMportEditFieldLabel = uilabel(app.UIFigure);
            app.COMportEditFieldLabel.Text = 'COM port';
            app.COMportEditField = uieditfield(app.UIFigure, 'numeric');
            app.COMportEditField.Limits = [0 Inf];
            app.COMportEditField.ValueChangedFcn = createCallbackFcn(app, @COMportEditFieldValueChanged, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.BitfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.BitfrequencyEditFieldLabel.Text = 'Bit frequency';
            app.BitfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.BitfrequencyEditField.Limits = [0 65535];
            app.BitfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @BitfrequencyEditFieldValueChanged, true);
            app.Codeword8hexEditFieldLabel = uilabel(app.UIFigure);
            app.Codeword8hexEditFieldLabel.Text = 'Code word (8 hex)';
            app.Codeword8hexEditField = uieditfield(app.UIFigure, 'text');
            app.Codeword8hexEditField.ValueChangedFcn = createCallbackFcn(app, @Codeword8hexEditFieldValueChanged, true);
            app.Codeword8hexEditField.Value = '0';
            app.CarrierFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarrierFrequencyEditField.Limits = [0 65535];
            app.CarrierFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencyEditFieldValueChanged, true);
            app.RepetitioncounterEditFieldLabel = uilabel(app.UIFigure);
            app.RepetitioncounterEditFieldLabel.Text = 'Repetition counter';
            app.RepetitioncounterEditField = uieditfield(app.UIFigure, 'numeric');
            app.RepetitioncounterEditField.Limits = [32 65535];
            app.RepetitioncounterEditField.ValueChangedFcn = createCallbackFcn(app, @RepetitioncounterEditFieldValueChanged, true);
            app.RepetitioncounterEditField.Value = 32;
            app.ConfigAudioButton = uibutton(app.UIFigure, 'push');
            app.ConfigAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigAudioButtonPushed, true);
            app.ConfigAudioButton.Text = 'Config Audio';
            app.PlayaudioButton = uibutton(app.UIFigure, 'push');
            app.PlayaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayaudioButtonPushed, true);
            app.PlayaudioButton.Text = 'Play audio';
            app.StopaudioButton = uibutton(app.UIFigure, 'push');
            app.StopaudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopaudioButtonPushed, true);
            app.StopaudioButton.Text = 'Stop audio';
            app.WASDdrivingButton = uibutton(app.UIFigure, 'push');
            app.WASDdrivingButton.ButtonPushedFcn = createCallbackFcn(app, @WASDdrivingButtonPushed, true);
            app.WASDdrivingButton.Text = 'WASD-driving';
            app.SteerGaugeLabel = uilabel(app.UIFigure);
            app.SteerGaugeLabel.Text = 'Steer';
            app.SteerGauge = uigauge(app.UIFigure, 'semicircular');
            app.SteerGauge.Limits = [100 200];
            app.SteerGauge.ScaleDirection = 'counterclockwise';
            app.SteerGauge.Value = 150;
            app.SpeedGaugeLabel = uilabel(app.UIFigure);
            app.SpeedGaugeLabel.Text = 'Speed';
            app.SpeedGauge = uigauge(app.UIFigure, 'linear');
            app.SpeedGauge.Limits = [135 165];
            app.SpeedGauge.MajorTicks = [135 142.5 150 157.5 165];
            app.SpeedGauge.Value = 150;
            app.AudioLampLabel = uilabel(app.UIFigure);
            app.AudioLampLabel.Text = 'Audio';
            app.AudioLamp = uilamp(app.UIFigure);
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end