classdef GUI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EPO4_controlUIFigure            matlab.ui.Figure
        STOPButton                      matlab.ui.control.Button
        COMportEditFieldLabel           matlab.ui.control.Label
        COMportEditField                matlab.ui.control.NumericEditField
        ConnectButton                   matlab.ui.control.Button
        BitfrequencyEditFieldLabel      matlab.ui.control.Label
        BitfrequencyEditField           matlab.ui.control.NumericEditField
        Codeword8hexEditFieldLabel      matlab.ui.control.Label
        Codeword8hexEditField           matlab.ui.control.EditField
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        RepetitioncounterEditFieldLabel  matlab.ui.control.Label
        RepetitioncounterEditField      matlab.ui.control.NumericEditField
        ConfigAudioButton               matlab.ui.control.Button
        PlayaudioButton                 matlab.ui.control.Button
        StopaudioButton                 matlab.ui.control.Button
        WASDdrivingButton               matlab.ui.control.Button
        SteerGaugeLabel                 matlab.ui.control.Label
        SteerGauge                      matlab.ui.control.SemicircularGauge
        SpeedGaugeLabel                 matlab.ui.control.Label
        SpeedGauge                      matlab.ui.control.LinearGauge
        AudioLampLabel                  matlab.ui.control.Label
        AudioLamp                       matlab.ui.control.Lamp
        ConnectionLampLabel             matlab.ui.control.Label
        ConnectionLamp                  matlab.ui.control.Lamp
        DistanceLeftLabel               matlab.ui.control.Label
        NaNLabel                        matlab.ui.control.Label
        VbatLabel                       matlab.ui.control.Label
        NaNLabel_3                      matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        NaNLabel_2                      matlab.ui.control.Label
    end


    properties (Access = public)
    end

    properties (Access = private)
        com % Com port to be used
        Fb % Bit frequency to be used
        Cw % Code word to be used
        Fc % Carrier frequency to be used
        Rc % Repetition counter to be used
        connection % Veriable to indicate if the car is connected
        updateTimer % Description
    end

    methods (Access = private)
    
        function stat = updateFunc(app)
            stat = EPOCommunications('transmit','S');
            if not(stat)
                app.ConnectionLamp.Color = [0 1 0];
            else
                app.NaNLabel.Text = 'NaN'; % Left sensor
                app.NaNLabel_2.Text = 'NaN'; % Right sensor
                app.NaNLabel_3.Text = 'NaN'; % V bat
                app.ConnectionLamp.Color = [1 0.5 0];
                app.AudioLamp.Color = [1 0.5 0];
            end
        end
        
        
        function setTimer(app)
            app.updateTimer = timer('BusyMode','drop','ExecutionMode','fixedSpacing','Period',0.1,'TimerFcn',updateFunc(app));
        end
        
    end


    methods (Access = private)

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stop();
            closeCom();
            app.ConnectionLamp.Color = [1 0 0];
            app.AudioLamp.Color = [1 0 0];
        end

        % Value changed function: COMportEditField
        function COMportEditFieldValueChanged(app, event)
            value = app.COMportEditField.Value;
            app.com = value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            str = sprintf('\\.\COM%d',app.com);
            tmp = EPOCommunications('open',str);
            if tmp
                app.connection = 1;
                app.ConnectionLamp.Color = [0 1 0];
                app.AudioLamp.Color = [0 1 0];
            else
                app.ConnectionLamp.Color = [1 0.5 0];
            end
            setTimer(app);
            start(app.updateTimer);
        end

        % Value changed function: BitfrequencyEditField
        function BitfrequencyEditFieldValueChanged(app, event)
            value = app.BitfrequencyEditField.Value;
            app.Fb = value;
        end

        % Value changed function: Codeword8hexEditField
        function Codeword8hexEditFieldValueChanged(app, event)
            value = app.Codeword8hexEditField.Value;
            if length(value) > 8 || hex2dec(value) > 4294967295
                app.Cw = 00000000;
            else
                app.Cw = dec2hex(hex2dec(value));
            end
        end

        % Value changed function: CarrierFrequencyEditField
        function CarrierFrequencyEditFieldValueChanged(app, event)
            value = app.CarrierFrequencyEditField.Value;
            app.Fc = value;
        end

        % Value changed function: RepetitioncounterEditField
        function RepetitioncounterEditFieldValueChanged(app, event)
            value = app.RepetitioncounterEditField.Value;
            app.Rc = value;
        end

        % Button pushed function: ConfigAudioButton
        function ConfigAudioButtonPushed(app, event)
            m1 = sprintf('B%d',app.Fb);
            m2 = sprintf('C%d',app.Cw);
            m3 = sprintf('F%d',app.Fc);
            m4 = sprintf('R%d',app.Rc);
            EPOCommunications('transmit',m1);
            EPOCommunications('transmit',m2);
            EPOCommunications('transmit',m3);
            EPOCommunications('transmit',m4);
            setTimer();
            app.AudioLamp.Color = [1 0.5 0];
        end

        % Button pushed function: PlayaudioButton
        function PlayaudioButtonPushed(app, event)
            EPOCommunications('transmit','A1');
            app.AudioLamp.Color = [0 1 0];
        end

        % Button pushed function: StopaudioButton
        function StopaudioButtonPushed(app, event)
            EPOCommunications('transmit','A0');
            app.AudioLamp.Color = [1 0 0];
        end

        % Button pushed function: WASDdrivingButton
        function WASDdrivingButtonPushed(app, event)
            control_script_1
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EPO4_controlUIFigure = uifigure;
            app.EPO4_controlUIFigure.Name = 'EPO4_control';
            app.STOPButton = uibutton(app.EPO4_controlUIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP!';
            app.COMportEditFieldLabel = uilabel(app.EPO4_controlUIFigure);
            app.COMportEditFieldLabel.Text = 'COM port';
            app.COMportEditField = uieditfield(app.EPO4_controlUIFigure, 'numeric');
            app.COMportEditField.Limits = [0 Inf];
            app.COMportEditField.ValueChangedFcn = createCallbackFcn(app, @COMportEditFieldValueChanged, true);
            app.ConnectButton = uibutton(app.EPO4_controlUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.BitfrequencyEditFieldLabel = uilabel(app.EPO4_controlUIFigure);
            app.BitfrequencyEditFieldLabel.Text = 'Bit frequency';
            app.BitfrequencyEditField = uieditfield(app.EPO4_controlUIFigure, 'numeric');
            app.BitfrequencyEditField.Limits = [0 65535];
            app.BitfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @BitfrequencyEditFieldValueChanged, true);
            app.Codeword8hexEditFieldLabel = uilabel(app.EPO4_controlUIFigure);
            app.Codeword8hexEditFieldLabel.Text = 'Code word (8 hex)';
            app.Codeword8hexEditField = uieditfield(app.EPO4_controlUIFigure, 'text');
            app.Codeword8hexEditField.ValueChangedFcn = createCallbackFcn(app, @Codeword8hexEditFieldValueChanged, true);
            app.Codeword8hexEditField.Value = '0';
            app.CarrierFrequencyEditFieldLabel = uilabel(app.EPO4_controlUIFigure);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.EPO4_controlUIFigure, 'numeric');
            app.CarrierFrequencyEditField.Limits = [0 65535];
            app.CarrierFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencyEditFieldValueChanged, true);
            app.RepetitioncounterEditFieldLabel = uilabel(app.EPO4_controlUIFigure);
            app.RepetitioncounterEditFieldLabel.Text = 'Repetition counter';
            app.RepetitioncounterEditField = uieditfield(app.EPO4_controlUIFigure, 'numeric');
            app.RepetitioncounterEditField.Limits = [32 65535];
            app.RepetitioncounterEditField.ValueChangedFcn = createCallbackFcn(app, @RepetitioncounterEditFieldValueChanged, true);
            app.RepetitioncounterEditField.Value = 32;
            app.ConfigAudioButton = uibutton(app.EPO4_controlUIFigure, 'push');
            app.ConfigAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigAudioButtonPushed, true);
            app.ConfigAudioButton.Text = 'Config Audio';
            app.PlayaudioButton = uibutton(app.EPO4_controlUIFigure, 'push');
            app.PlayaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayaudioButtonPushed, true);
            app.PlayaudioButton.Text = 'Play audio';
            app.StopaudioButton = uibutton(app.EPO4_controlUIFigure, 'push');
            app.StopaudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopaudioButtonPushed, true);
            app.StopaudioButton.Text = 'Stop audio';
            app.WASDdrivingButton = uibutton(app.EPO4_controlUIFigure, 'push');
            app.WASDdrivingButton.ButtonPushedFcn = createCallbackFcn(app, @WASDdrivingButtonPushed, true);
            app.WASDdrivingButton.Text = 'WASD-driving';
            app.SteerGaugeLabel = uilabel(app.EPO4_controlUIFigure);
            app.SteerGaugeLabel.Text = 'Steer';
            app.SteerGauge = uigauge(app.EPO4_controlUIFigure, 'semicircular');
            app.SteerGauge.Limits = [100 200];
            app.SteerGauge.ScaleDirection = 'counterclockwise';
            app.SteerGauge.Value = 150;
            app.SpeedGaugeLabel = uilabel(app.EPO4_controlUIFigure);
            app.SpeedGaugeLabel.Text = 'Speed';
            app.SpeedGauge = uigauge(app.EPO4_controlUIFigure, 'linear');
            app.SpeedGauge.Limits = [135 165];
            app.SpeedGauge.MajorTicks = [135 142.5 150 157.5 165];
            app.SpeedGauge.Value = 150;
            app.AudioLampLabel = uilabel(app.EPO4_controlUIFigure);
            app.AudioLampLabel.Text = 'Audio';
            app.AudioLamp = uilamp(app.EPO4_controlUIFigure);
            app.ConnectionLampLabel = uilabel(app.EPO4_controlUIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.EPO4_controlUIFigure);
            app.DistanceLeftLabel = uilabel(app.EPO4_controlUIFigure);
            app.DistanceLeftLabel.Text = 'Distance:     Left:';
            app.NaNLabel = uilabel(app.EPO4_controlUIFigure);
            app.NaNLabel.Text = 'NaN';
            app.VbatLabel = uilabel(app.EPO4_controlUIFigure);
            app.VbatLabel.Text = 'Vbat';
            app.NaNLabel_3 = uilabel(app.EPO4_controlUIFigure);
            app.NaNLabel_3.Text = 'NaN';
            app.RightLabel = uilabel(app.EPO4_controlUIFigure);
            app.RightLabel.Text = '                   Right:';
            app.NaNLabel_2 = uilabel(app.EPO4_controlUIFigure);
            app.NaNLabel_2.Text = 'NaN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EPO4_controlUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EPO4_controlUIFigure)
        end
    end
end